classdef gui_assignment_3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure  matlab.ui.Figure
        DatasetButtonGroup             matlab.ui.container.ButtonGroup
        Patient1Button                 matlab.ui.control.RadioButton
        Patient2Button                 matlab.ui.control.RadioButton
        TrainNeuralNetworkPanel        matlab.ui.container.Panel
        NetworkTypeDropDownLabel       matlab.ui.control.Label
        NetworkTypeDropDown            matlab.ui.control.DropDown
        GoalDropDownLabel              matlab.ui.control.Label
        GoalDropDown                   matlab.ui.control.DropDown
        TrainingDropDownLabel          matlab.ui.control.Label
        TrainingDropDown               matlab.ui.control.DropDown
        ClassBalanceDropDownLabel      matlab.ui.control.Label
        ClassBalanceDropDown           matlab.ui.control.DropDown
        NeuronsperLayersDropDownLabel  matlab.ui.control.Label
        NeuronsperLayersDropDown       matlab.ui.control.DropDown
        TrainandTestButton             matlab.ui.control.Button
        SpecializationErrorDropDownLabel  matlab.ui.control.Label
        SpecializationErrorDropDown    matlab.ui.control.DropDown
        NumberOfFeaturesDropDownLabel  matlab.ui.control.Label
        NumberOfFeaturesDropDown       matlab.ui.control.DropDown
        NumberOfFeaturesDropDownLabel_2  matlab.ui.control.Label
        NumberOfFeaturesDropDown_2     matlab.ui.control.DropDown
        TestTrainedNeuralNetworkPanel  matlab.ui.container.Panel
        TestButton                     matlab.ui.control.Button
        DropDown                       matlab.ui.control.DropDown
        ClassificationTypeDropDown_2Label  matlab.ui.control.Label
        ClassificationTypeDropDown_2   matlab.ui.control.DropDown
        ResultsPanel                   matlab.ui.container.Panel
        SensitivityLabel               matlab.ui.control.Label
        SpecificityLabel               matlab.ui.control.Label
        LabelSpecificity               matlab.ui.control.Label
        LabelSensitivity               matlab.ui.control.Label
        PredictionLabel                matlab.ui.control.Label
        DetectionLabel                 matlab.ui.control.Label
        SensitivityLabel_2             matlab.ui.control.Label
        SpecificityLabel_2             matlab.ui.control.Label
        LabelSpecificity_2             matlab.ui.control.Label
        LabelSensitivity_2             matlab.ui.control.Label
        TruePositivesLabel             matlab.ui.control.Label
        LabelTP                        matlab.ui.control.Label
        TrueNegativeLabel              matlab.ui.control.Label
        LabelTN                        matlab.ui.control.Label
        FalsePositivesLabel            matlab.ui.control.Label
        LabelFP                        matlab.ui.control.Label
        FalseNegativesLabel            matlab.ui.control.Label
        LabelFN                        matlab.ui.control.Label
        TruePositivesLabel_2           matlab.ui.control.Label
        LabelTP2                       matlab.ui.control.Label
        TrueNegativeLabel_2            matlab.ui.control.Label
        LabelTN2                       matlab.ui.control.Label
        FalsePositivesLabel_2          matlab.ui.control.Label
        LabelFP2                       matlab.ui.control.Label
        FalseNegativesLabel_2          matlab.ui.control.Label
        LabelFN2                       matlab.ui.control.Label
        DatasetBuildLampLabel          matlab.ui.control.Label
        DatasetBuildLamp               matlab.ui.control.Lamp
        NetworkTrainedLampLabel        matlab.ui.control.Label
        NetworkTrainedLamp             matlab.ui.control.Lamp
        NetworkSavedLampLabel          matlab.ui.control.Label
        NetworkSavedLamp               matlab.ui.control.Lamp
        NetworkSavedLampLabel_2        matlab.ui.control.Label
        NetworkTestedLamp              matlab.ui.control.Lamp
    end

    
    methods (Access = private)
        
        function update_trained_nets(app)
            trained_nets = dir('trained_nets'); % Folder structure
            
            names = strings; % Files name - Empty character vector
            for i = 3:length(trained_nets)
                name = trained_nets(i,1).name;
                names(i - 2,1) = name;
            end
            
            app.DropDown.Items = names;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_trained_nets(app)
        end

        % Button pushed function: TrainandTestButton
        function TrainandTestButtonPushed(app, event)
            disp('Train')
            
            datasetValue = app.DatasetButtonGroup.SelectedObject.Text;
            
            dataset_idx = 0;
            type = app.NetworkTypeDropDown.Value;
            goal = app.GoalDropDown.Value;
            specialization = app.SpecializationErrorDropDown.Value;
            training_data_perc = str2double(app.TrainingDropDown.Value);
            balance = str2double(app.ClassBalanceDropDown.Value);
            num_neurons = str2double(app.NeuronsperLayersDropDown.Value);
            num_features = str2double(app.NumberOfFeaturesDropDown.Value);
            
            clf_type = app.ClassificationTypeDropDown_2.Value;      
            
            if strcmp(datasetValue,'Patient 1')
                dataset_idx = 1;
            elseif strcmp(datasetValue,'Patient 2')
                dataset_idx = 2;
            end
            
            app.DatasetBuildLamp.Color = 'red';
            app.NetworkTrainedLamp.Color = 'red';
            app.NetworkSavedLamp.Color = 'red';
            app.NetworkTestedLamp.Color = 'red';
            
            
            % Build dataset
            [P_train,T_train, P_test, T_test] = build_dataset(dataset_idx, ...
                num_features, training_data_perc, balance);
            app.DatasetBuildLamp.Color = 'green';
            
            % Train network
            trained_net = train_network(P_train, T_train, type, num_neurons, ...
                goal,specialization);
            app.NetworkTrainedLamp.Color = 'green';
            
            % Save network
            save_trained_net(P_test,T_test, trained_net, dataset_idx, type, ...
                goal, specialization, training_data_perc, balance,num_neurons, num_features);
            app.NetworkSavedLamp.Color = 'green';
            
            % Test Network
            if strcmp(type,'LSTM') || strcmp(type,'CNN')
                [prediction, detection] = test_deep_network(clf_type, trained_net, P_test, T_test);
            else
                [prediction, detection] = test_network(clf_type, trained_net, P_test, T_test);  
            end
            app.NetworkTestedLamp.Color = 'green';
            
            % Prediction Performance
            app.LabelSensitivity.Text = num2str(prediction.sensitivity);
            app.LabelSpecificity.Text = num2str(prediction.specificity);
            app.LabelTP.Text = num2str(prediction.TP);
            app.LabelTN.Text = num2str(prediction.TN);
            app.LabelFP.Text = num2str(prediction.FP);
            app.LabelFN.Text = num2str(prediction.FN);
            
            % Detection Performance
            app.LabelSensitivity_2.Text = num2str(detection.sensitivity);
            app.LabelSpecificity_2.Text = num2str(detection.specificity);
            app.LabelTP2.Text = num2str(detection.TP);
            app.LabelTN2.Text = num2str(detection.TN);
            app.LabelFP2.Text = num2str(detection.FP);
            app.LabelFN2.Text = num2str(detection.FN);
            
            update_trained_nets(app)
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            disp('Test')
            
            app.DatasetBuildLamp.Color = 'black';
            app.NetworkTrainedLamp.Color = 'black';
            app.NetworkSavedLamp.Color = 'black';
            app.NetworkTestedLamp.Color = 'black';
            
           
            clf_type = app.ClassificationTypeDropDown_2.Value;
            trained_net_name = app.DropDown.Value;
            path = strcat('trained_nets/',trained_net_name);
            load(path)
            
            % Test Network
            if strcmp(trained_net_name(4:7),'LSTM') || strcmp(trained_net_name(4:6),'CNN')
                [prediction, detection] = test_deep_network(clf_type, trained_net, P_test, T_test);
            else
                [prediction, detection] = test_network(clf_type, trained_net, P_test, T_test);  
            end
            app.NetworkTestedLamp.Color = 'green';
            
            % Prediction Performance
            app.LabelSensitivity.Text = num2str(prediction.sensitivity);
            app.LabelSpecificity.Text = num2str(prediction.specificity);
            app.LabelTP.Text = num2str(prediction.TP);
            app.LabelTN.Text = num2str(prediction.TN);
            app.LabelFP.Text = num2str(prediction.FP);
            app.LabelFN.Text = num2str(prediction.FN);
            
            % Detection Performance
            app.LabelSensitivity_2.Text = num2str(detection.sensitivity);
            app.LabelSpecificity_2.Text = num2str(detection.specificity);
            app.LabelTP2.Text = num2str(detection.TP);
            app.LabelTN2.Text = num2str(detection.TN);
            app.LabelFP2.Text = num2str(detection.FP);
            app.LabelFN2.Text = num2str(detection.FN);   
        end

        % Callback function
        function DropDownValueChanged(app, event)
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure = uifigure('Visible', 'off');
            app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure.Name = 'Assigment #3 - Prediction and Detection of Epileptic Seizures';
            app.DatasetButtonGroup = uibuttongroup(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.DatasetButtonGroup.Title = 'Dataset';
            app.Patient1Button = uiradiobutton(app.DatasetButtonGroup);
            app.Patient1Button.Text = 'Patient 1';
            app.Patient1Button.Value = true;
            app.Patient2Button = uiradiobutton(app.DatasetButtonGroup);
            app.Patient2Button.Text = 'Patient 2';
            app.TrainNeuralNetworkPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TrainNeuralNetworkPanel.Title = 'Train Neural Network';
            app.NetworkTypeDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.NetworkTypeDropDownLabel.Text = 'Network Type';
            app.NetworkTypeDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.NetworkTypeDropDown.Items = {'Multilayer', 'Multilayer with Delays', 'CNN', 'LSTM'};
            app.NetworkTypeDropDown.Value = 'Multilayer';
            app.GoalDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.GoalDropDownLabel.Text = 'Goal';
            app.GoalDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.GoalDropDown.Items = {'Prediction', 'Detection'};
            app.GoalDropDown.Value = 'Prediction';
            app.TrainingDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.TrainingDropDownLabel.Text = 'Training %';
            app.TrainingDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.TrainingDropDown.Items = {'0.8', '0.7'};
            app.TrainingDropDown.Value = '0.8';
            app.ClassBalanceDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.ClassBalanceDropDownLabel.Text = 'Class Balance';
            app.ClassBalanceDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.ClassBalanceDropDown.Items = {'1', '10'};
            app.ClassBalanceDropDown.Value = '1';
            app.NeuronsperLayersDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.NeuronsperLayersDropDownLabel.Text = 'Neurons per Layers';
            app.NeuronsperLayersDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.NeuronsperLayersDropDown.Items = {'10', '20', '40'};
            app.NeuronsperLayersDropDown.Value = '20';
            app.TrainandTestButton = uibutton(app.TrainNeuralNetworkPanel, 'push');
            app.TrainandTestButton.ButtonPushedFcn = createCallbackFcn(app, @TrainandTestButtonPushed, true);
            app.TrainandTestButton.Text = 'Train and Test';
            app.SpecializationErrorDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.SpecializationErrorDropDownLabel.Text = 'Specialization (Error)';
            app.SpecializationErrorDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.SpecializationErrorDropDown.Items = {'High', 'Medium', 'None'};
            app.SpecializationErrorDropDown.Value = 'High';
            app.NumberOfFeaturesDropDownLabel = uilabel(app.TrainNeuralNetworkPanel);
            app.NumberOfFeaturesDropDownLabel.Text = 'Number of Features';
            app.NumberOfFeaturesDropDown = uidropdown(app.TrainNeuralNetworkPanel);
            app.NumberOfFeaturesDropDown.Items = {'29', '25', '20', '10'};
            app.NumberOfFeaturesDropDown.Value = '29';
            app.NumberOfFeaturesDropDownLabel_2 = uilabel(app.TrainNeuralNetworkPanel);
            app.NumberOfFeaturesDropDownLabel_2.Text = 'Classification Type';
            app.NumberOfFeaturesDropDown_2 = uidropdown(app.TrainNeuralNetworkPanel);
            app.NumberOfFeaturesDropDown_2.Items = {'Single', 'Group'};
            app.NumberOfFeaturesDropDown_2.Value = 'Single';
            app.TestTrainedNeuralNetworkPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.TestTrainedNeuralNetworkPanel.Title = 'Test Trained Neural Network';
            app.TestButton = uibutton(app.TestTrainedNeuralNetworkPanel, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.DropDown = uidropdown(app.TestTrainedNeuralNetworkPanel);
            app.DropDown.Items = {'d1_Multilayer_Prediction_High_80_ClassBalanced_20_29.mat', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Value = 'd1_Multilayer_Prediction_High_80_ClassBalanced_20_29.mat';
            app.ClassificationTypeDropDown_2Label = uilabel(app.TestTrainedNeuralNetworkPanel);
            app.ClassificationTypeDropDown_2Label.Text = 'Classification Type';
            app.ClassificationTypeDropDown_2 = uidropdown(app.TestTrainedNeuralNetworkPanel);
            app.ClassificationTypeDropDown_2.Items = {'Single', 'Group'};
            app.ClassificationTypeDropDown_2.Value = 'Single';
            app.ResultsPanel = uipanel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.ResultsPanel.Title = 'Results';
            app.SensitivityLabel = uilabel(app.ResultsPanel);
            app.SensitivityLabel.Text = 'Sensitivity:';
            app.SpecificityLabel = uilabel(app.ResultsPanel);
            app.SpecificityLabel.Text = 'Specificity:';
            app.LabelSpecificity = uilabel(app.ResultsPanel);
            app.LabelSpecificity.Text = '0';
            app.LabelSensitivity = uilabel(app.ResultsPanel);
            app.LabelSensitivity.Text = '0';
            app.PredictionLabel = uilabel(app.ResultsPanel);
            app.PredictionLabel.Text = 'Prediction';
            app.DetectionLabel = uilabel(app.ResultsPanel);
            app.DetectionLabel.Text = 'Detection';
            app.SensitivityLabel_2 = uilabel(app.ResultsPanel);
            app.SensitivityLabel_2.Text = 'Sensitivity:';
            app.SpecificityLabel_2 = uilabel(app.ResultsPanel);
            app.SpecificityLabel_2.Text = 'Specificity:';
            app.LabelSpecificity_2 = uilabel(app.ResultsPanel);
            app.LabelSpecificity_2.Text = '0';
            app.LabelSensitivity_2 = uilabel(app.ResultsPanel);
            app.LabelSensitivity_2.Text = '0';
            app.TruePositivesLabel = uilabel(app.ResultsPanel);
            app.TruePositivesLabel.Text = 'True Positives:';
            app.LabelTP = uilabel(app.ResultsPanel);
            app.LabelTP.Text = '0';
            app.TrueNegativeLabel = uilabel(app.ResultsPanel);
            app.TrueNegativeLabel.Text = 'True Negative:';
            app.LabelTN = uilabel(app.ResultsPanel);
            app.LabelTN.Text = '0';
            app.FalsePositivesLabel = uilabel(app.ResultsPanel);
            app.FalsePositivesLabel.Text = 'False Positives:';
            app.LabelFP = uilabel(app.ResultsPanel);
            app.LabelFP.Text = '0';
            app.FalseNegativesLabel = uilabel(app.ResultsPanel);
            app.FalseNegativesLabel.Text = 'False Negatives:';
            app.LabelFN = uilabel(app.ResultsPanel);
            app.LabelFN.Text = '0';
            app.TruePositivesLabel_2 = uilabel(app.ResultsPanel);
            app.TruePositivesLabel_2.Text = 'True Positives:';
            app.LabelTP2 = uilabel(app.ResultsPanel);
            app.LabelTP2.Text = '0';
            app.TrueNegativeLabel_2 = uilabel(app.ResultsPanel);
            app.TrueNegativeLabel_2.Text = 'True Negative:';
            app.LabelTN2 = uilabel(app.ResultsPanel);
            app.LabelTN2.Text = '0';
            app.FalsePositivesLabel_2 = uilabel(app.ResultsPanel);
            app.FalsePositivesLabel_2.Text = 'False Positives:';
            app.LabelFP2 = uilabel(app.ResultsPanel);
            app.LabelFP2.Text = '0';
            app.FalseNegativesLabel_2 = uilabel(app.ResultsPanel);
            app.FalseNegativesLabel_2.Text = 'False Negatives:';
            app.LabelFN2 = uilabel(app.ResultsPanel);
            app.LabelFN2.Text = '0';
            app.DatasetBuildLampLabel = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.DatasetBuildLampLabel.Text = 'Dataset Build';
            app.DatasetBuildLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkTrainedLampLabel = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkTrainedLampLabel.Text = 'Network Trained';
            app.NetworkTrainedLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel.Text = 'Network Saved';
            app.NetworkSavedLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel_2 = uilabel(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            app.NetworkSavedLampLabel_2.Text = 'Network Tested';
            app.NetworkTestedLamp = uilamp(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure);
            % Show the figure after all components are created
            app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_assignment_3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Assigment3PredictionandDetectionofEpilepticSeizuresUIFigure)
        end
    end
end